classdef transmisor_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        ComenzarButton         matlab.ui.control.StateButton
        CodificacionCheckBox   matlab.ui.control.CheckBox
        ModulacionButtonGroup  matlab.ui.container.ButtonGroup
        PSKButton              matlab.ui.control.RadioButton
        QPSKButton             matlab.ui.control.RadioButton
        BPSKButton             matlab.ui.control.RadioButton
        SNR_dBSlider           matlab.ui.control.Slider
        SNR_dBSliderLabel      matlab.ui.control.Label
        RightPanel             matlab.ui.container.Panel
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes1                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ComenzarButton
        function ComenzarButtonValueChanged(app, event)
            if app.ComenzarButton.Value == false, return; end
            % Seleccionar el tipo de moduacion que se quiere
            tipo_modulacion = app.ModulacionButtonGroup.SelectedObject.Text;
            % Seleccionar el nivel de ruido que se quiere.
            SNR_dB_actual = app.SNR_dBSlider.Value;
            % Ver si se quiere codificaco o no.
            estado_codificacion = app.CodificacionCheckBox.Value;


            % Parametros de simulacion
            n_bits = 1e4;
            roll_off = 0.3;
            spp = 8;

            datos_originales = randi([0 1],1,n_bits);
            if estado_codificacion
                bits_transmitir = codificar_convolucion(datos_originales,[171,133]);
                R = 1/2;
                nombre_codificacion = 'Viterbi';
            else
                bits_transmitir = datos_originales;    
                R = 1;
                nombre_codificacion = 'ninguna';
            end

            switch tipo_modulacion
                case 'QPSK'
                    simbolos = modulador_qpsk(bits_transmitir);
                    k = 2;
                case 'BPSK'
                    simbolos = modulador_bpsk(bits_transmitir);
                    k = 1;
                case '8-PSK'
                    simbolos = modulador_8psk(bits_transmitir);
                    k = 3;
            end

            % canal
            snr_canal = SNR_dB_actual + 10*log10(k*R);
            senial_ruidosa = canal_awgn(simbolos,snr_canal);

            %Grafica de constelacion 
            cla(app.UIAxes1);
            plot(app.UIAxes1, real(senial_ruidosa), imag(senial_ruidosa), '.', 'MarkerSize', 10);
            hold(app.UIAxes1,'on');
            xline(app.UIAxes1, 0, 'Color', [0.2 0.2 0.2], 'LineWidth', 1.5);
            yline(app.UIAxes1, 0, 'Color', [0.2 0.2 0.2], 'LineWidth', 1.5);
            grid(app.UIAxes1, 'on');
            xlabel(app.UIAxes1, 'En Fase (I)');
            ylabel(app.UIAxes1, 'Cuadratura (Q)');
            title(app.UIAxes1, ['Constelación Recibida - ', tipo_modulacion]);
            axis(app.UIAxes1, 'equal');
            app.UIAxes1.XLim = [-3 3];
            app.UIAxes1.YLim = [-3 3];
            hold(app.UIAxes1, 'off');

            
            SNR_dB = 0:1:15;
            BER_Practico = zeros(1,length(SNR_dB));
            EbNo = 10.^(SNR_dB/10);

            M=2^k;
            SER_teorico = 2*qfunc(sqrt(2*log2(M)*EbNo*(sin(pi/M)^2))); 
            BER_Teorico = SER_teorico/k;

            for snr = SNR_dB
                snr_canal = snr + 10*log10(k*R);
                senial_ruidosa = canal_awgn(simbolos,snr_canal);
                switch tipo_modulacion
                    case 'QPSK' 
                        bits_demodulados = demodulador_qpsk(senial_ruidosa);
                    case 'BPSK'
                        bits_demodulados = demodulador_bpsk(senial_ruidosa);
                    case '8-PSK'
                        bits_demodulados = demodulador_8psk(senial_ruidosa);
                end
                
                if estado_codificacion
                    bits_obtenidos = decodificar_viterbi(bits_demodulados);
                else
                    bits_obtenidos = bits_demodulados(1:length(datos_originales));
                end
                errores = sum(xor(datos_originales,bits_obtenidos));
                BER_Practico(snr+1) = errores/length(datos_originales);
            end

            cla(app.UIAxes2);
            semilogy(app.UIAxes2, SNR_dB, BER_Practico, '-o', 'LineWidth', 1.5, 'MarkerFaceColor', 'b');
            hold(app.UIAxes2,'on');
            semilogy(app.UIAxes2, SNR_dB, BER_Teorico, 'LineWidth', 1.5, 'MarkerFaceColor', 'r');
            grid(app.UIAxes2, 'on');
            legend(app.UIAxes2,['Practico' tipo_modulacion],['Teorico' tipo_modulacion]);
            xlabel(app.UIAxes2, 'SNR (dB)');
            ylabel(app.UIAxes2, 'BER');
            title(app.UIAxes2, 'BER vs SNR (dB)');
            
            app.ComenzarButton.Value = false;


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {762, 762};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {316, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SNR_dBSliderLabel = uilabel(app.LeftPanel);
            app.SNR_dBSliderLabel.Text = 'SNR_dB';
            app.SNR_dBSlider = uislider(app.LeftPanel);
            app.SNR_dBSlider.Limits = [0 20];
            app.SNR_dBSlider.MajorTicks = [0 5 10 15 20];
            app.ModulacionButtonGroup = uibuttongroup(app.LeftPanel);
            app.ModulacionButtonGroup.Title = 'Modulacion';
            app.BPSKButton = uiradiobutton(app.ModulacionButtonGroup);
            app.BPSKButton.Text = 'BPSK';
            app.BPSKButton.Value = true;
            app.QPSKButton = uiradiobutton(app.ModulacionButtonGroup);
            app.QPSKButton.Text = 'QPSK';
            app.PSKButton = uiradiobutton(app.ModulacionButtonGroup);
            app.PSKButton.Text = '8-PSK';
            app.CodificacionCheckBox = uicheckbox(app.LeftPanel);
            app.CodificacionCheckBox.Text = 'Codificacion';
            app.ComenzarButton = uibutton(app.LeftPanel, 'state');
            app.ComenzarButton.ValueChangedFcn = createCallbackFcn(app, @ComenzarButtonValueChanged, true);
            app.ComenzarButton.Text = 'Comenzar';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes1 = uiaxes(app.RightPanel);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = transmisor_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end